classdef DIP_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image2_5                     matlab.ui.control.Image
        Image2_4                     matlab.ui.control.Image
        OperationsLabel              matlab.ui.control.Label
        Image2_3                     matlab.ui.control.Image
        Image2_2                     matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        PreloadedImagesLabel         matlab.ui.control.Label
        NoisyImagesButton            matlab.ui.control.Button
        FrequencyDomainButton        matlab.ui.control.Button
        FilteringButton              matlab.ui.control.Button
        IntensityOperationsButton    matlab.ui.control.Button
        InterpolationButton          matlab.ui.control.Button
        SelectImageButton            matlab.ui.control.Button
        ORIGINALIMAGELabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        TabGroup                     matlab.ui.container.TabGroup
        InterpolationTab             matlab.ui.container.Tab
        AnticlockwiseRotationButton  matlab.ui.control.Button
        RGBtoGREYButton              matlab.ui.control.Button
        ClockwiseRotationButton      matlab.ui.control.Button
        BicubicInterpolationButton   matlab.ui.control.Button
        BilinearInterpolationButton  matlab.ui.control.Button
        NearestNeighbourhoodButton   matlab.ui.control.Button
        biLinear                     matlab.ui.control.UIAxes
        nearNeighbour                matlab.ui.control.UIAxes
        biCubic                      matlab.ui.control.UIAxes
        antiClock                    matlab.ui.control.UIAxes
        greyImage                    matlab.ui.control.UIAxes
        clockRot                     matlab.ui.control.UIAxes
        IntensityOperationsTab       matlab.ui.container.Tab
        HistogramEqualizationButton  matlab.ui.control.Button
        EnhanceContrastButton        matlab.ui.control.Button
        ShowHistogramButton          matlab.ui.control.Button
        ApplyEffectButton            matlab.ui.control.Button
        SharpenButton                matlab.ui.control.Button
        histogramImage               matlab.ui.control.UIAxes
        contrastImage                matlab.ui.control.UIAxes
        logTransform                 matlab.ui.control.UIAxes
        sharpening                   matlab.ui.control.UIAxes
        sharpening2                  matlab.ui.control.UIAxes
        FilteringTab                 matlab.ui.container.Tab
        highBoostButton              matlab.ui.control.Button
        medianFilterButton           matlab.ui.control.Button
        gaussianFilterButton         matlab.ui.control.Button
        weightedFilterButton         matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        boxFilterButton              matlab.ui.control.Button
        highBoostFilterImage         matlab.ui.control.UIAxes
        medianFilterImage            matlab.ui.control.UIAxes
        gaussianFilterImage          matlab.ui.control.UIAxes
        weightedFilterImage          matlab.ui.control.UIAxes
        BlurImage                    matlab.ui.control.UIAxes
        FrequencyDomainTab           matlab.ui.container.Tab
        butterworthHighButton        matlab.ui.control.Button
        idealHighpassButton          matlab.ui.control.Button
        butterworthButton            matlab.ui.control.Button
        idealLowpassButton           matlab.ui.control.Button
        butterworthHighImage         matlab.ui.control.UIAxes
        idealHighpassImage           matlab.ui.control.UIAxes
        butterworthImage             matlab.ui.control.UIAxes
        idealLowpassImage            matlab.ui.control.UIAxes
        NoisyImagesTab               matlab.ui.container.Tab
        saltandPepperButton          matlab.ui.control.Button
        arithmeticNoiseButton        matlab.ui.control.Button
        rayleighNoiseButton          matlab.ui.control.Button
        gaussianNoiseImage           matlab.ui.control.Button
        saltandPepperNoiseImage      matlab.ui.control.UIAxes
        arthmeticNoiseImage          matlab.ui.control.UIAxes
        rayleighNoiseImage           matlab.ui.control.UIAxes
        guassianNoiseButtonImage     matlab.ui.control.UIAxes
        OriginalImageLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        UIAxes2 % Description
    end

    
    methods (Access = public)
        
        function PQ = paddedsize(AB, CD, PARAM)
                %PADDEDSIZE Computes padded sizes useful for FFT-based filtering.
                % PQ = PADDEDSIZE(AB), where AB is a two-element size vector, computes the
                % two-element size vecter PQ = 2*AB.
                %
                % PQ = PADDEDSIZE(AB, 'PWR2') computes the vector PQ such that PQ(1) =
                % PQ(2) = 2^nextpow2(2*m), where m is MAX(AB).
                %
                % PQ = PADDEDSIZE(AB, CD), where AB and CD are two-element size vectors,
                % computes the two-element size vector PQ. The elements of PQ are the
                % smallest even integers greater than or equal to AB + CD -1.
                %
                % PQ = PADDEDSIZE(AB, CD, 'PWR2') computes the vector PQ such that PQ(1) =
                % PQ(2) = 2^nextpow2(2*m), where m is MAX([AB CD]).
                %
                % Function rewritten from Gonzalez, Woods, Eddins; Digital Image Processing using Matlab
                % Prentice Hall, 2004

                if nargin ==1
                    PQ = 2*AB;
                elseif nargin == 2 & ~ischar(CD)
                    PQ = AB +CD -1;
                    PQ = 2*ceil(PQ/2);
                elseif nargin ==2
                    m= max(AB); %Maximum dimension
                    
                    %Find power-of-2 at least twice m.
                	P = 2^nextpow2(2*m);
                	PQ = [P, P];
                elseif nargin == 3
                    m=max([AB CD]); % Maximum dimension.
                    P = 2^nextpow2(2*m);
                    PQ = [P, P];
                else
                    error('Wrong number of inputs')
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function SelectImageButtonPushed(app, event)
            
        end

        % Callback function
        function RemoveButtonPushed(app, event)
            app.Image.DeleteFcn;
        end

        % Callback function
        function OperationDropDownOpening(app, event)
       
        end

        % Callback function
        function NNInterpolationButtonPushed(app, event)
          
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed2(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.gif';'*.tiff';'*.bmp'; '*.png'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Button pushed function: NearestNeighbourhoodButton
        function NearestNeighbourhoodButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            nn = imresize(img,0.6,'nearest');
            imshow(nn, 'Parent', app.nearNeighbour);
        end

        % Callback function
        function inputImageClicked(app, event)
            img = imread(app.Image.ImageSource);
            app.inputImage.ImageSource = img;
        end

        % Button pushed function: BilinearInterpolationButton
        function BilinearInterpolationButtonPushed(app, event)
         img = imread(app.Image.ImageSource);
            bi = imresize(img,0.6,'bilinear');
            imshow(bi, 'Parent', app.biLinear);     
        end

        % Button pushed function: BicubicInterpolationButton
        function BicubicInterpolationButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            bc = imresize(img,0.6,'bicubic');
            imshow(bc, 'Parent', app.biCubic);   
        end

        % Button pushed function: RGBtoGREYButton
        function RGBtoGREYButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            greyImage = rgb2gray(img);
            imshow(greyImage, 'Parent', app.greyImage);
        end

        % Button pushed function: ClockwiseRotationButton
        function ClockwiseRotationButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            clockRot = imrotate(img,180);
            imshow(clockRot, 'Parent', app.clockRot);
        end

        % Button pushed function: AnticlockwiseRotationButton
        function AnticlockwiseRotationButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            antiClock = imrotate(img,270);
            imshow(antiClock, 'Parent', app.antiClock); 
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file,path] = uigetfile({'.jpeg';'.gif';'.tiff';'.jpg';'.bmp'; '.png'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            I = imread(app.Image.ImageSource);
            imshow(histeq(I),'Parent',app.histogramImage);
        end

        % Button pushed function: boxFilterButton
        function boxFilterButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            BoxFilter = imboxfilt(img,11);
            imshow(BoxFilter,'Parent',app.BlurImage);
        end

        % Callback function
        function AddEffectButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            Noi_img = imnoise(img,'salt & pepper', 0.2);
            imshow(Noi_img,'Parent',app.noiseImage);
        end

        % Callback function
        function RemoveNoiseButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            rmMed = medfilt3(img);
            imshow(rmMed,'Parent',app.noiseImage);
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            I = imread(app.Image.ImageSource);
            J = histeq(I);
            subplot(2,1,1),imhist(I,64); title('Org image histogram');
            subplot(2,1,2),imhist(J,64);title('Equalized histogram');
        end

        % Button pushed function: EnhanceContrastButton
        function EnhanceContrastButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            LowHigh = stretchlim(img, [0.05, 0.95]); 
            stretchedImage = imadjust(img, LowHigh,[]);
            imshow(stretchedImage,'Parent',app.contrastImage);
        end

        % Button pushed function: ApplyEffectButton
        function ApplyEffectButtonPushed(app, event)
            img = imread(app.Image.ImageSource); 
            img2 = double(img)/255;
            c = 2; % Constant
            imgLT = c*log(1 + (img2)); % Log Transform
            imshow(imgLT,'Parent',app.logTransform);
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            w4 = [0,1,0;1,-4,1;0,1,0];
            w8 = [1,1,1;1,-8,1;1,1,1];
            img=im2double(img);
            f4=imfilter(img,w4,'replicate');
            f8=imfilter(img,w8,'replicate');
            img4=img-f4; %org image minus w4 based filtered image
            img8=img-f8; %org image minus w8 based filtered image
            imshow(img8,'Parent',app.sharpening);
            imshow(img4,'Parent',app.sharpening2);
        end

        % Button pushed function: weightedFilterButton
        function weightedFilterButtonPushed(app, event)
            img=imread(app.Image.ImageSource); 
            H=1/9*ones(3,3); 
            greyImage = rgb2gray(img);
            imf = filter2(H,greyImage);
            imfilt = uint8(imf);
            imshow(imfilt,'Parent',app.weightedFilterImage);
        end

        % Button pushed function: gaussianFilterButton
        function gaussianFilterButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            GaussianFilter = imgaussfilt(img,2); % with std dev=2, 'FilterSizem,3 (i.e. k)   
            imshow(GaussianFilter,'Parent',app.gaussianFilterImage);
        end

        % Button pushed function: medianFilterButton
        function medianFilterButtonPushed(app, event)
            I = imread(app.Image.ImageSource);
            J = imnoise(I,'salt & pepper',0.2);
            
            r = medfilt2(J(:, :, 1), [3 3]);
            g = medfilt2(J(:, :, 2), [3 3]);
            b = medfilt2(J(:, :, 3), [3 3]);
 
            img = cat(3, r, g, b);
            imshow(img, 'Parent', app.medianFilterImage);
        end

        % Button pushed function: highBoostButton
        function highBoostButtonPushed(app, event)
            I = imread(app.Image.ImageSource);
            
            I1= imgaussfilt(I,5,'FilterSize',31); %Generating Mask using Gaussian
            I2 = I-I1; %Unsharping
            
            k=4.5; 
            img4=I+k*I2;
            imshow(img4,'Parent',app.highBoostFilterImage);
        end

        % Callback function
        function frequencyDomainSobelButtonPushed(app, event)

        end

        % Button pushed function: idealLowpassButton
        function idealLowpassButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            [M, N] = size(img);
            FT_img = fft2(double(img));
            
            D0 = 30;
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;
            
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);
            H = double(D <= D0);
            G = H.*FT_img;
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.idealLowpassImage);
            
        end

        % Button down function: idealLowpassImage
        function idealLowpassImageButtonDown(app, event)
            
        end

        % Button down function: butterworthImage
        function butterworthImageButtonDown(app, event)
            
        end

        % Button pushed function: butterworthButton
        function butterworthButtonPushed(app, event)
            img=imread(app.Image.ImageSource);

            [M, N] = size(img);
            FT_img = fft2(double(img));
            n = 2;
            D0 = 20; 
            u = 0:(M-1);
            v = 0:(N-1);
            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);
            H = 1./(1 + (D./D0).^(2*n));
            G = H.*FT_img;
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.butterworthImage);

        end

        % Button pushed function: butterworthHighButton
        function butterworthHighButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            [M, N] = size(img);
            FT_img = fft2(double(img));
            n = 2; 
            D0 = 20; 
            u = 0:(M-1);
            v = 0:(N-1);
            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);
            H = 1./(1 + (D./D0).^(2*n));
            G = H.*(1-FT_img);
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.butterworthHighImage);
        end

        % Button pushed function: idealHighpassButton
        function idealHighpassButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            [M, N] = size(img); 
            FT_img = fft2(double(img));
            D0 = 10;
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);
            H = double(D > D0);
            G = H.*FT_img;  
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.idealHighpassImage);
        end

        % Button pushed function: gaussianNoiseImage
        function gaussianNoiseImageButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            img2 = imnoise(img,'gaussian',0.02,0.2);
            imshow(img2,'Parent',app.guassianNoiseButtonImage);
        end

        % Button pushed function: rayleighNoiseButton
        function rayleighNoiseButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            N = 50; 
            Ic = NaN(size(img,1),size(img,2),N); 
            sigma = 1.5;
            Mult_noise = NaN(size(img));
            for ID_realz = 1:1:N
                rng('shuffle');
                Mult_noise = (sigma*randn(size(img)))+((1j)*(sigma*randn(size(img)))); 
                Ic(:,:,ID_realz) = abs(img.*Mult_noise);      
                clear('Mult_noise');
            end
            img2 = imshow(Ic(:,:,3),[]);
            imshow(Img2,'Parent',app.gaussianNoiseImage);
        end

        % Button pushed function: arithmeticNoiseButton
        function arithmeticNoiseButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            gauss_cktb = imread(app.Image.ImageSource);
            mask_size =3; 
            arith_mask = ones(mask_size)/mask_size^2;
            arith_denoised_cktb = conv2(gauss_cktb,arith_mask,'same');
            imshow(arith_denoised_cktb,'Parent',app.arthmeticNoiseImage);
        end

        % Callback function
        function InterpolationButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            
            nn = imresize(img,0.6,'nearest');
            imshow(nn, 'Parent', app.nearNeighbour);
            
            bi = imresize(img,0.6,'bilinear');
            imshow(bi, 'Parent', app.biLinear); 

            bc = imresize(img,0.6,'bicubic');
            imshow(bc, 'Parent', app.biCubic); 

            greyImage = rgb2gray(img);
            imshow(greyImage, 'Parent', app.greyImage);

            clockRot = imrotate(img,180);
            imshow(clockRot, 'Parent', app.clockRot);

            antiClock = imrotate(img,270);
            imshow(antiClock, 'Parent', app.antiClock); 
        end

        % Button pushed function: IntensityOperationsButton
        function IntensityOperationsButtonPushed(app, event)
            img = imread(app.Image.ImageSource);
            
            %HistogramEq
            imshow(histeq(I),'Parent',app.histogramImage);

            %ContrastStretching
            LowHigh = stretchlim(img, [0.05, 0.95]); 
            stretchedImage = imadjust(img, LowHigh,[]);
            imshow(stretchedImage,'Parent',app.contrastImage);
            
            %LAPLACIAN
            w4 = [0,1,0;1,-4,1;0,1,0];
            w8 = [1,1,1;1,-8,1;1,1,1];
            img=im2double(img);
            f4=imfilter(img,w4,'replicate');
            f8=imfilter(img,w8,'replicate');
            img4=img-f4; %org image minus w4 based filtered image
            img8=img-f8; %org image minus w8 based filtered image
            imshow(img8,'Parent',app.sharpening);
            imshow(img4,'Parent',app.sharpening2);
            
            %LogTransform
            mg2 = double(img)/255;
            c = 2; % Constant
            imgLT = c*log(1 + (img2)); % Log Transform
            imshow(imgLT,'Parent',app.logTransform);
        end

        % Button pushed function: FilteringButton
        function FilteringButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            
            %GaussianFilter
            GaussianFilter = imgaussfilt(img,2); % with std dev=2, 'FilterSizem,3 (i.e. k)   
            imshow(GaussianFilter,'Parent',app.gaussianFilterImage);
            
            %BoxFilter
            BoxFilter = imboxfilt(img,11);
            imshow(BoxFilter,'Parent',app.BlurImage);

            %WeightedMean
            H=1/9*ones(3,3); 
            greyImage = rgb2gray(img);
            imf = filter2(H,greyImage);
            imfilt = uint8(imf);
            imshow(imfilt,'Parent',app.weightedFilterImage);

            %SharpingMean
            J = imnoise(img,'salt & pepper',0.2);
            
            r = medfilt2(J(:, :, 1), [3 3]);
            g = medfilt2(J(:, :, 2), [3 3]);
            b = medfilt2(J(:, :, 3), [3 3]);
 
            img2 = cat(3, r, g, b);
            imshow(img2, 'Parent', app.medianFilterImage);

            %HighBoost
            I1= imgaussfilt(img,5,'FilterSize',31); %Generating Mask using Gaussian
            I2 = img-I1; %Unsharping
            
            k=4.5; 
            img4=img+k*I2;
            imshow(img4,'Parent',app.highBoostFilterImage);
        end

        % Button pushed function: saltandPepperButton
        function saltandPepperButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            Noi_img = imnoise(img,'salt & pepper', 0.2);
            imshow(Noi_img,'Parent',app.saltandPepperNoiseImage);

            
        end

        % Button pushed function: FrequencyDomainButton
        function FrequencyDomainButtonPushed(app, event)
            img=imread(app.Image.ImageSource);
            %IdealLowPass
            [M, N] = size(img);
            FT_img = fft2(double(img));
            
            D0 = 30;
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;
            
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);
            H = double(D <= D0);
            G = H.*FT_img;
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.idealLowpassImage);

            %ButterworthLowpass
            [M, N] = size(img);
            FT_img = fft2(double(img));
            n = 2;
            D0 = 20; 
            u = 0:(M-1);
            v = 0:(N-1);
            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);
            H = 1./(1 + (D./D0).^(2*n));
            G = H.*FT_img;
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.butterworthImage);

            %ButterworthHighpass
            [M, N] = size(img);
            FT_img = fft2(double(img));
            n = 2; 
            D0 = 20; 
            u = 0:(M-1);
            v = 0:(N-1);
            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);
            H = 1./(1 + (D./D0).^(2*n));
            G = H.*(1-FT_img);
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.butterworthHighImage);

            %IdealHighPass
            [M, N] = size(img); 
            FT_img = fft2(double(img));
            D0 = 10;
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);
            H = double(D > D0);
            G = H.*FT_img;  
            img2 = real(ifft2(double(G)));
            imshow(img2,'Parent',app.idealHighpassImage);
        end

        % Button pushed function: NoisyImagesButton
        function NoisyImagesButtonPushed(app, event)
            img=imread(app.Image.ImageSource);

            %GaussianNoise
            img2 = imnoise(img,'gaussian',0.02,0.2);
            imshow(img2,'Parent',app.gaussianNoiseImage);

            %RayleighNoise
            N = 50; 
            Ic = NaN(size(img,1),size(img,2),N); 
            sigma = 1.5;
            Mult_noise = NaN(size(img));
            for ID_realz = 1:1:N
                rng('shuffle');
                Mult_noise = (sigma*randn(size(img)))+((1j)*(sigma*randn(size(img)))); 
                Ic(:,:,ID_realz) = abs(img.*Mult_noise);      
                clear('Mult_noise');
            end
            img2 = imshow(Ic(:,:,3),[]);
            imshow(Img2,'Parent',app.gaussianNoiseImage);

            %ArithmeticMean
            auss_cktb = imread(app.Image.ImageSource);
            mask_size =3; 
            arith_mask = ones(mask_size)/mask_size^2;
            arith_denoised_cktb = conv2(gauss_cktb,arith_mask,'same');
            imshow(arith_denoised_cktb,'Parent',app.arthmeticNoiseImage);

            %Salt&Pepper
            Noi_img = imnoise(img,'salt & pepper', 0.2);
            imshow(Noi_img,'Parent',app.saltandPepperNoiseImage);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            imshow(app.Image2, 'Parent', app.Image);
        end

        % Image clicked function: Image2_2
        function Image2_2Clicked(app, event)
            imshow(app.Image2_2, 'Parent', app.Image);
        end

        % Image clicked function: Image2_3
        function Image2_3Clicked(app, event)
            imshow(app.Image2_3, 'Parent', app.Image);
        end

        % Image clicked function: Image2_4
        function Image2_4Clicked(app, event)
            imshow(app.Image2_4, 'Parent', app.Image);
        end

        % Image clicked function: Image2_5
        function Image2_5Clicked(app, event)
            imshow(app.Image2_5, 'Parent', app.Image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.clockRot = uiaxes(app.InterpolationTab);
            title(app.clockRot, 'Clockwise Rotation')
            app.clockRot.XTick = [];
            app.clockRot.YTick = [];
            app.greyImage = uiaxes(app.InterpolationTab);
            title(app.greyImage, 'RGB to Grey')
            app.greyImage.XTick = [];
            app.greyImage.YTick = [];
            app.antiClock = uiaxes(app.InterpolationTab);
            title(app.antiClock, 'Anticlock Rotation')
            app.antiClock.XTick = [];
            app.antiClock.YTick = [];
            app.biCubic = uiaxes(app.InterpolationTab);
            title(app.biCubic, 'Bicubic ')
            app.biCubic.XTick = [];
            app.biCubic.YTick = [];
            app.nearNeighbour = uiaxes(app.InterpolationTab);
            title(app.nearNeighbour, 'Nearest Neighbourhood')
            app.nearNeighbour.XTick = [];
            app.nearNeighbour.YTick = [];
            app.biLinear = uiaxes(app.InterpolationTab);
            title(app.biLinear, 'Bilinear')
            app.biLinear.XTick = [];
            app.biLinear.YTick = [];
            app.NearestNeighbourhoodButton = uibutton(app.InterpolationTab, 'push');
            app.NearestNeighbourhoodButton.ButtonPushedFcn = createCallbackFcn(app, @NearestNeighbourhoodButtonPushed, true);
            app.NearestNeighbourhoodButton.Text = 'Nearest Neighbourhood';
            app.BilinearInterpolationButton = uibutton(app.InterpolationTab, 'push');
            app.BilinearInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @BilinearInterpolationButtonPushed, true);
            app.BilinearInterpolationButton.Text = 'Bilinear Interpolation';
            app.BicubicInterpolationButton = uibutton(app.InterpolationTab, 'push');
            app.BicubicInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @BicubicInterpolationButtonPushed, true);
            app.BicubicInterpolationButton.Text = 'Bicubic Interpolation';
            app.ClockwiseRotationButton = uibutton(app.InterpolationTab, 'push');
            app.ClockwiseRotationButton.ButtonPushedFcn = createCallbackFcn(app, @ClockwiseRotationButtonPushed, true);
            app.ClockwiseRotationButton.Text = 'Clockwise Rotation';
            app.RGBtoGREYButton = uibutton(app.InterpolationTab, 'push');
            app.RGBtoGREYButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoGREYButtonPushed, true);
            app.RGBtoGREYButton.Text = 'RGB to GREY';
            app.AnticlockwiseRotationButton = uibutton(app.InterpolationTab, 'push');
            app.AnticlockwiseRotationButton.ButtonPushedFcn = createCallbackFcn(app, @AnticlockwiseRotationButtonPushed, true);
            app.AnticlockwiseRotationButton.Text = 'Anticlockwise Rotation';
            app.IntensityOperationsTab = uitab(app.TabGroup);
            app.IntensityOperationsTab.Title = 'Intensity Operations';
            app.sharpening2 = uiaxes(app.IntensityOperationsTab);
            title(app.sharpening2, 'Sharpening Laplacian 4')
            app.sharpening2.XTick = [];
            app.sharpening2.YTick = [];
            app.sharpening = uiaxes(app.IntensityOperationsTab);
            title(app.sharpening, 'Sharpening Laplacian 8')
            app.sharpening.XTick = [];
            app.sharpening.YTick = [];
            app.logTransform = uiaxes(app.IntensityOperationsTab);
            title(app.logTransform, 'Log Transform')
            app.logTransform.XTick = [];
            app.logTransform.YTick = [];
            app.contrastImage = uiaxes(app.IntensityOperationsTab);
            title(app.contrastImage, 'Contrast Stretching')
            app.contrastImage.XTick = [];
            app.contrastImage.YTick = [];
            app.histogramImage = uiaxes(app.IntensityOperationsTab);
            title(app.histogramImage, 'Histogram Equalization')
            app.histogramImage.XTick = [];
            app.histogramImage.YTick = [];
            app.SharpenButton = uibutton(app.IntensityOperationsTab, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.ApplyEffectButton = uibutton(app.IntensityOperationsTab, 'push');
            app.ApplyEffectButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyEffectButtonPushed, true);
            app.ApplyEffectButton.Text = 'Apply Effect';
            app.ShowHistogramButton = uibutton(app.IntensityOperationsTab, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            app.EnhanceContrastButton = uibutton(app.IntensityOperationsTab, 'push');
            app.EnhanceContrastButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceContrastButtonPushed, true);
            app.EnhanceContrastButton.Text = 'Enhance Contrast';
            app.HistogramEqualizationButton = uibutton(app.IntensityOperationsTab, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.BlurImage = uiaxes(app.FilteringTab);
            title(app.BlurImage, 'Blurring (Box Filter)')
            app.BlurImage.XTick = [];
            app.BlurImage.YTick = [];
            app.weightedFilterImage = uiaxes(app.FilteringTab);
            title(app.weightedFilterImage, 'Blurring (Weighted Average Filter)')
            app.weightedFilterImage.XTick = [];
            app.weightedFilterImage.YTick = [];
            app.gaussianFilterImage = uiaxes(app.FilteringTab);
            title(app.gaussianFilterImage, 'Blurring (Gaussian Filter)')
            app.gaussianFilterImage.XTick = [];
            app.gaussianFilterImage.YTick = [];
            app.medianFilterImage = uiaxes(app.FilteringTab);
            title(app.medianFilterImage, 'Sharpening (Median Filter)')
            app.medianFilterImage.XTick = [];
            app.medianFilterImage.YTick = [];
            app.highBoostFilterImage = uiaxes(app.FilteringTab);
            title(app.highBoostFilterImage, 'Highboost Filter')
            app.highBoostFilterImage.XTick = [];
            app.highBoostFilterImage.YTick = [];
            app.boxFilterButton = uibutton(app.FilteringTab, 'push');
            app.boxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @boxFilterButtonPushed, true);
            app.boxFilterButton.Text = 'Apply Effect';
            app.Label = uilabel(app.FilteringTab);
            app.weightedFilterButton = uibutton(app.FilteringTab, 'push');
            app.weightedFilterButton.ButtonPushedFcn = createCallbackFcn(app, @weightedFilterButtonPushed, true);
            app.weightedFilterButton.Text = 'Apply Effect';
            app.gaussianFilterButton = uibutton(app.FilteringTab, 'push');
            app.gaussianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @gaussianFilterButtonPushed, true);
            app.gaussianFilterButton.Text = 'Apply Effect';
            app.medianFilterButton = uibutton(app.FilteringTab, 'push');
            app.medianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @medianFilterButtonPushed, true);
            app.medianFilterButton.Text = 'Apply Effect';
            app.highBoostButton = uibutton(app.FilteringTab, 'push');
            app.highBoostButton.ButtonPushedFcn = createCallbackFcn(app, @highBoostButtonPushed, true);
            app.highBoostButton.Text = 'Apply Effect';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.idealLowpassImage = uiaxes(app.FrequencyDomainTab);
            title(app.idealLowpassImage, 'Ideal Lowpass Filtering')
            app.idealLowpassImage.XTick = [];
            app.idealLowpassImage.YTick = [];
            app.idealLowpassImage.ButtonDownFcn = createCallbackFcn(app, @idealLowpassImageButtonDown, true);
            app.butterworthImage = uiaxes(app.FrequencyDomainTab);
            title(app.butterworthImage, 'Butterworth Lowpass Filtering')
            app.butterworthImage.XTick = [];
            app.butterworthImage.YTick = [];
            app.butterworthImage.ButtonDownFcn = createCallbackFcn(app, @butterworthImageButtonDown, true);
            app.idealHighpassImage = uiaxes(app.FrequencyDomainTab);
            title(app.idealHighpassImage, 'Ideal Highpass Filtering')
            app.idealHighpassImage.XTick = [];
            app.idealHighpassImage.YTick = [];
            app.butterworthHighImage = uiaxes(app.FrequencyDomainTab);
            title(app.butterworthHighImage, 'Butterworth Highpass Filtering')
            app.butterworthHighImage.XTick = [];
            app.butterworthHighImage.YTick = [];
            app.idealLowpassButton = uibutton(app.FrequencyDomainTab, 'push');
            app.idealLowpassButton.ButtonPushedFcn = createCallbackFcn(app, @idealLowpassButtonPushed, true);
            app.idealLowpassButton.Text = 'Add Effect';
            app.butterworthButton = uibutton(app.FrequencyDomainTab, 'push');
            app.butterworthButton.ButtonPushedFcn = createCallbackFcn(app, @butterworthButtonPushed, true);
            app.butterworthButton.Text = 'Add Effect';
            app.idealHighpassButton = uibutton(app.FrequencyDomainTab, 'push');
            app.idealHighpassButton.ButtonPushedFcn = createCallbackFcn(app, @idealHighpassButtonPushed, true);
            app.idealHighpassButton.Text = 'Add Effect';
            app.butterworthHighButton = uibutton(app.FrequencyDomainTab, 'push');
            app.butterworthHighButton.ButtonPushedFcn = createCallbackFcn(app, @butterworthHighButtonPushed, true);
            app.butterworthHighButton.Text = 'Add Effect';
            app.NoisyImagesTab = uitab(app.TabGroup);
            app.NoisyImagesTab.Title = 'Noisy Images';
            app.guassianNoiseButtonImage = uiaxes(app.NoisyImagesTab);
            title(app.guassianNoiseButtonImage, 'Gaussian Noise')
            app.guassianNoiseButtonImage.XTick = [];
            app.guassianNoiseButtonImage.YTick = [];
            app.rayleighNoiseImage = uiaxes(app.NoisyImagesTab);
            title(app.rayleighNoiseImage, 'Rayleigh Noise')
            app.rayleighNoiseImage.XTick = [];
            app.rayleighNoiseImage.YTick = [];
            app.arthmeticNoiseImage = uiaxes(app.NoisyImagesTab);
            title(app.arthmeticNoiseImage, 'Arithmetic Mean Noise')
            app.arthmeticNoiseImage.XTick = [];
            app.arthmeticNoiseImage.YTick = [];
            app.saltandPepperNoiseImage = uiaxes(app.NoisyImagesTab);
            title(app.saltandPepperNoiseImage, 'Salt and Pepper Noise')
            app.saltandPepperNoiseImage.XTick = [];
            app.saltandPepperNoiseImage.YTick = [];
            app.gaussianNoiseImage = uibutton(app.NoisyImagesTab, 'push');
            app.gaussianNoiseImage.ButtonPushedFcn = createCallbackFcn(app, @gaussianNoiseImageButtonPushed, true);
            app.gaussianNoiseImage.Text = 'Add Effect';
            app.rayleighNoiseButton = uibutton(app.NoisyImagesTab, 'push');
            app.rayleighNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @rayleighNoiseButtonPushed, true);
            app.rayleighNoiseButton.Text = 'Add Effect';
            app.arithmeticNoiseButton = uibutton(app.NoisyImagesTab, 'push');
            app.arithmeticNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @arithmeticNoiseButtonPushed, true);
            app.arithmeticNoiseButton.Text = 'Add Effect';
            app.saltandPepperButton = uibutton(app.NoisyImagesTab, 'push');
            app.saltandPepperButton.ButtonPushedFcn = createCallbackFcn(app, @saltandPepperButtonPushed, true);
            app.saltandPepperButton.Text = 'Add Effect';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1-13-500x429.jpg');
            app.ORIGINALIMAGELabel = uilabel(app.UIFigure);
            app.ORIGINALIMAGELabel.Text = 'ORIGINAL IMAGE';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed2, true);
            app.SelectImageButton.Text = 'Select Image';
            app.InterpolationButton = uibutton(app.UIFigure, 'push');
            app.InterpolationButton.Text = 'Interpolation';
            app.IntensityOperationsButton = uibutton(app.UIFigure, 'push');
            app.IntensityOperationsButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityOperationsButtonPushed, true);
            app.IntensityOperationsButton.Text = 'Intensity Operations';
            app.FilteringButton = uibutton(app.UIFigure, 'push');
            app.FilteringButton.ButtonPushedFcn = createCallbackFcn(app, @FilteringButtonPushed, true);
            app.FilteringButton.Text = 'Filtering';
            app.FrequencyDomainButton = uibutton(app.UIFigure, 'push');
            app.FrequencyDomainButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyDomainButtonPushed, true);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.NoisyImagesButton = uibutton(app.UIFigure, 'push');
            app.NoisyImagesButton.ButtonPushedFcn = createCallbackFcn(app, @NoisyImagesButtonPushed, true);
            app.NoisyImagesButton.Text = 'Noisy Images';
            app.PreloadedImagesLabel = uilabel(app.UIFigure);
            app.PreloadedImagesLabel.Text = 'Pre-loaded Images';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images.jpg');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @Image2_2Clicked, true);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'lena.jpg');
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageClickedFcn = createCallbackFcn(app, @Image2_3Clicked, true);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'Normal_posteroanterior_(PA)_chest_radiograph_(X-ray).jpg');
            app.OperationsLabel = uilabel(app.UIFigure);
            app.OperationsLabel.Text = 'Operations';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageClickedFcn = createCallbackFcn(app, @Image2_4Clicked, true);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'onion.png');
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageClickedFcn = createCallbackFcn(app, @Image2_5Clicked, true);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, '1-13-500x429.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIP_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end